classdef Engine_Performance_model_201152378 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        EnginePerformanceModelQ3Tab    matlab.ui.container.Tab
        RunButton                      matlab.ui.control.Button
        MECH3496THERMOFLUIDS3CW2Q3AEROSPACEPROPULSIONASSIGNMENTLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        Image2                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        BypassRatioEditFieldLabel      matlab.ui.control.Label
        BypassRatioEditField           matlab.ui.control.NumericEditField
        NormalisedSpoolSpeedEditFieldLabel  matlab.ui.control.Label
        NormalisedSpoolSpeedEditField  matlab.ui.control.NumericEditField
        CruiseSpeedinMachEditFieldLabel  matlab.ui.control.Label
        CruiseSpeedinMachEditField     matlab.ui.control.NumericEditField
        TurbineEntryTemperatureKEditFieldLabel  matlab.ui.control.Label
        TurbineEntryTemperatureKEditField  matlab.ui.control.NumericEditField
        IntakeNmultiplierkgsEditFieldLabel  matlab.ui.control.Label
        IntakeNmultiplierkgsEditField  matlab.ui.control.NumericEditField
        LPCEditFieldLabel              matlab.ui.control.Label
        LPCEditField                   matlab.ui.control.NumericEditField
        HPCEditFieldLabel              matlab.ui.control.Label
        HPCEditField                   matlab.ui.control.NumericEditField
        LPTEditFieldLabel              matlab.ui.control.Label
        LPTEditField                   matlab.ui.control.NumericEditField
        HPTEditFieldLabel              matlab.ui.control.Label
        HPTEditField                   matlab.ui.control.NumericEditField
        HPmEditFieldLabel              matlab.ui.control.Label
        HPmEditField                   matlab.ui.control.NumericEditField
        LPmEditFieldLabel              matlab.ui.control.Label
        LPmEditField                   matlab.ui.control.NumericEditField
        CruiseAltitudemEditFieldLabel  matlab.ui.control.Label
        CruiseAltitudemEditField       matlab.ui.control.NumericEditField
        IntakePREditFieldLabel         matlab.ui.control.Label
        IntakePREditField              matlab.ui.control.NumericEditField
        IntakeTREditFieldLabel         matlab.ui.control.Label
        IntakeTREditField              matlab.ui.control.NumericEditField
        CombustorPREditFieldLabel      matlab.ui.control.Label
        CombustorPREditField           matlab.ui.control.NumericEditField
        HPCPRmultiplierEditFieldLabel  matlab.ui.control.Label
        HPCPRmultiplierEditField       matlab.ui.control.NumericEditField
        JetPipePREditFieldLabel        matlab.ui.control.Label
        JetPipePREditField             matlab.ui.control.NumericEditField
        JetPipeTREditFieldLabel        matlab.ui.control.Label
        JetPipeTREditField             matlab.ui.control.NumericEditField
        BypassNozzlePREditFieldLabel   matlab.ui.control.Label
        BypassNozzlePREditField        matlab.ui.control.NumericEditField
        BypassNozzleTREditFieldLabel   matlab.ui.control.Label
        BypassNozzleTREditField        matlab.ui.control.NumericEditField
        CoreNozzlePREditFieldLabel     matlab.ui.control.Label
        CoreNozzlePREditField          matlab.ui.control.NumericEditField
        CoreNozzleTREditFieldLabel     matlab.ui.control.Label
        CoreNozzleTREditField          matlab.ui.control.NumericEditField
        CCEditFieldLabel               matlab.ui.control.Label
        CCEditField                    matlab.ui.control.NumericEditField
        ThrustSpecificFuelConsumptiongkNsEditFieldLabel  matlab.ui.control.Label
        ThrustSpecificFuelConsumptiongkNsEditField  matlab.ui.control.NumericEditField
        NetUninstalledThrustkNEditFieldLabel  matlab.ui.control.Label
        NetUninstalledThrustkNEditField  matlab.ui.control.NumericEditField
        InstructionsTextAreaLabel      matlab.ui.control.Label
        InstructionsTextArea           matlab.ui.control.TextArea
        ErrorEditFieldLabel            matlab.ui.control.Label
        ErrorEditField                 matlab.ui.control.EditField
        AlteringTETandHPCPRQ4Tab       matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        CalculateButton                matlab.ui.control.Button
        TETMinimumTSFCEditFieldLabel   matlab.ui.control.Label
        TETMinimumTSFCEditField        matlab.ui.control.NumericEditField
        HPCPressureRatiomultiplierEditFieldLabel  matlab.ui.control.Label
        HPCPressureRatiomultiplierEditField  matlab.ui.control.NumericEditField
        HelpTab                        matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: RunButton, UITable
        function RunButtonPushed2(app, event)
%Specify the constants needed to complete the calculation (non-variables)
%Constants
gamma_cold=1.4;     %The cold heat capacity ratio
gamma_hot=1.333;    %The hot heat capacity ratio
Cp_cold=1005;       %Specific heat capacity of cold air
Cp_hot=1150;        %Specific heat capacity of hot air
R_cold=287;         %Cold gas constant
R_hot=287.3;        %Hot gas constant
LHV=43.1e+06;       %Fuel Lower Heating Value

%Set the parameters for the calculation as the values defined by the app
%user in the interface
Cruise_altitude=app.CruiseAltitudemEditField.Value ;        %Altitude of flight (m)
BPR=app.BypassRatioEditField.Value ;                        %Bypass Ratio (ratio of mass of cold air to the bypass duct vs mass of hot air to the engine)
N=app.NormalisedSpoolSpeedEditField.Value ;                 %Throttle setting, impacting the air intake mass flow rate, LPC ratio and HPC ratio 
M0=app.CruiseSpeedinMachEditField.Value;                    %Cruise Speed (in Mach)
TET=app.TurbineEntryTemperatureKEditField.Value ;           %Temperature of air at the exit of the combustor (K)
m_air_N_multiplier=app.IntakeNmultiplierkgsEditField.Value; %Value to be multiplied by 'N' to equal the intake air mass flow rate (kg/s)

eta_LPC=app.LPCEditField.Value ;        %LP Compressor isentropic efficiency
eta_HPC=app.HPCEditField.Value ;        %HP Compressor isentropic efficiency
eta_LPT=app.LPTEditField.Value ;        %LP Turbine isentropic efficiency
eta_HPT=app.HPTEditField.Value ;        %HP Turbine isentropic efficiency
eta_HPM=app.HPmEditField.Value ;        %HP Spool mechanical efficiency
eta_LPM=app.LPmEditField.Value ;        %LP Spool mechanical efficiency
eta_CC=app.CCEditField.Value ;          %Combustion efficiency

IPR=app.IntakePREditField.Value ;           %Intake Pressure Recovery
ITR=app.IntakeTREditField.Value ;           %Intake Temperature Recovery
PR_CC=app.CombustorPREditField.Value ;      %Combustor Pressure Ratio
HPCPR=app.HPCPRmultiplierEditField.Value;   %Computational value used for data testing, leave as default
JPPR=app.JetPipePREditField.Value ;         %Jet Pipe Pressure Recovery
JPTR=app.JetPipeTREditField.Value ;         %Jet Pipe Temperature Recovery
CNPR=app.CoreNozzlePREditField.Value ;      %Core Nozzle Pressure Recovery
CNTR=app.CoreNozzleTREditField.Value ;      %Core Nozzle Temperature Recovery
BNPR=app.BypassNozzlePREditField.Value ;    %Bypass Nozzle Pressure Recovery
BNTR=app.BypassNozzleTREditField.Value ;    %Bypass Nozzle Temperature Recovery

%--------------------------------------------------------------------------
%INTAKE Stage 0--1
%The entry temperature, pressure and speed of sound are first calculated
%for the inputted cruise altitudes
T0 = 216.65;
a0 = 0;
P0= 19416;

%The velocity of the air flowing into the intake is then calculated
V0=M0*a0;

%Total Pressure and temperature are determined from isentropic
%relationships
Pt0=P0*(1+((gamma_cold-1)/2)*(M0^2))^(gamma_cold/(gamma_cold-1));
Tt0=T0*(Pt0/P0)^((gamma_cold-1)/(gamma_cold));

%Account for the pressure and temperature losses in the intake system
Pt1=Pt0*IPR;
Tt1=Tt0*ITR;
%--------------------------------------------------------------------------
%LP COMPRESSOR Stage 1--2
%Pressure ratio across the LPC is calculated
PR_crit_cold=((gamma_cold+1)/2)^(gamma_cold/(gamma_cold-1));
PR_LPC=PR_crit_cold*P0/(Pt1*BNPR);

%Pressure ratio across the LPC is used to find total pressure
Pt2=PR_LPC*Pt1;

%Isentropic relationships are used to find the ideal total temperature
Tt_2=Tt1*((Pt2/Pt1)^((gamma_cold-1)/gamma_cold));

%Actual temperature is found using the isentropic efficiency
Tt2=((Tt_2-Tt1)/eta_LPC)+Tt1;
%--------------------------------------------------------------------------
%HP COMPRESSOR Stage 2--3
%HP compressor pressure ratio can be calculated using given N value
PR_HPC=HPCPR*26.7*N^1.22;

%Pressure ratio across the HPC is used to find total pressure
Pt3=PR_HPC*Pt2;

%Isentropic relationships are used to find the ideal total temperature
Tt_3=Tt2*((Pt3/Pt2)^((gamma_cold-1)/gamma_cold));

%Actual temperature is found using the isentropic efficiency
Tt3=((Tt_3-Tt2)/eta_HPC)+Tt2;
%--------------------------------------------------------------------------
%COMBUSTOR Stage 3--4
%The total exit temperature of the combustor is given
Tt4=TET;

%Exit pressure is calculated using the combustor pressure recovery
Pt4=PR_CC*Pt3;

%Energy balance is used to calculate the Air-to-Fuel ratio, AFR
AFR=((LHV*eta_CC)/(Cp_hot*(Tt4-Tt3)))-1;
%--------------------------------------------------------------------------
%HP TURBINE Stage 4--5
%Energy balance can be used to determine Tt5 and Pt5
Tt5=Tt4-((AFR*Cp_cold*(Tt3-Tt2))/((AFR+1)*Cp_hot*eta_HPM));

%Pt5 can be calculated using the isentropic temperature Tt_5
Tt_5=Tt4-((Tt4-Tt5)/eta_HPT);
Pt5=Pt4*(Tt_5/Tt4)^(gamma_hot/(gamma_hot-1));
%--------------------------------------------------------------------------
%LP TURBINE Stage 5--6
%Energy balance can be used to determine Tt6 and Pt6
Tt6=(Tt5-((BPR+1)*AFR*Cp_cold*(Tt2-Tt1))/((AFR+1)*Cp_hot*eta_LPM));

%Isentropic relationship is used to calculate Pt6
Tt_6=Tt5-(Tt5-Tt6)/eta_LPT;
Pt6=Pt5*(Tt_6/Tt5)^(gamma_hot/(gamma_hot-1));
%--------------------------------------------------------------------------
%JET PIPE Stage 6--7
%Calculate Pt7 and Tt7 using the given jet pipe properties
Pt7=Pt6*JPPR;
Tt7=Tt6*JPTR;
%--------------------------------------------------------------------------
%CORE (HOT JET) NOZZLE Stage 7--8
%Calculate Pt8 and Tt8 using the given core nozzle properties
Pt8=Pt7*CNPR;
Tt8=Tt7*CNTR;

%A convergent nozzle is used in this instance. Therefore, a choke test must
%be performed.
PR_crit_hot=((gamma_hot+1)/2)^(gamma_hot/(gamma_hot-1));
PR_ideal_hot=Pt8/P0;

%This program can only calculate choked flow, and hence an if clause is
%used to check
if PR_ideal_hot>=PR_crit_hot
    %If the nozzle is choked, the flow is fully expanded and hence M8=1.0
    M8=1.0;

%Knowing the flow is choked, static properties at the nozzle can be calculated 
TR_crit_hot=(gamma_hot+1)/2;
P8=Pt8/PR_crit_hot;
T8=Tt8/TR_crit_hot;
V8=M8*(gamma_hot*R_hot*T8)^0.5;

%If the flow is not choked, another method must be used to calculate the
%total properties at the exit of the nozzle
else 
    P8=P0;
    T8=Tt8/((Pt8/P0)^((gamma_hot-1)/gamma_hot));
    V8=(2*Cp_hot*(Tt8-T8))^0.5;
end

%Check to make sure that V8 doesn't return a negative number and warn the
%user is so
tf = isreal(V8);

if tf==0
   
    app.ErrorEditField.Value='TET too low, returns imaginary values!';
    app.NetUninstalledThrustkNEditField.Value=0;
    app.ThrustSpecificFuelConsumptiongkNsEditField.Value=0;
else
        
%Assuming the nozzle was designed for this condition, (or it is of variable
%design), the hot thrust can be calculated. First, the mass flows need to
%be defined.
m_air=N*m_air_N_multiplier;
m_core=(m_air)/(BPR+1);
m_bypass=m_air-m_core;
m_fuel=m_core/AFR;
m_8=m_core+m_fuel;

%The nozzle exit area can then be calculated
A8=(m_8*R_hot*T8)/(P8*V8);

%Thrust is calculated using the uninstalled thrust equation
Thrust_hot=(m_8*V8)+A8*(P8-P0)-(m_core*V0);
%--------------------------------------------------------------------------
%BYPASS NOZZLE Stage 2--9
%No heat addition through the bypass nozzle
Tt9=BNTR*Tt2;

%Pressure recovery is given
Pt9=BNPR*Pt2;

%Choked flow is a known condition from generating PR_LPC, and therefore,
M9=1.0;

TR_crit_cold=(gamma_cold+1)/2;
P9=Pt9/PR_crit_cold;
T9=Tt9/TR_crit_cold;

%Velocity of the flow can be calculated using the Mach number
V9=M9*(gamma_cold*R_cold*T9)^0.5;
m_9=m_bypass;

%Nozzle area is again calculated using the assumption listed above
A9=(m_9*R_cold*T9)/(P9*V9);

%The cold thrust can be calculated using the uninstalled thrust equation
Thrust_cold=(m_9*(V9-V0))+A9*(P9-P0);

%Total thrust is calculated by summing the hot and cold thrust values
net_uninstalled_thrust=Thrust_cold+Thrust_hot; %in N

%The Thrust specific fuel consumption can therefore be calculated, and
%converted into standard units of g/Ns
TSFC=m_fuel/net_uninstalled_thrust*1000000; %in g/Ns
net_uninstalled_thrust=net_uninstalled_thrust/1000; %converts to kN

%Values are assigned to their boxes in the app interface
    app.NetUninstalledThrustkNEditField.Value=net_uninstalled_thrust;
    app.ThrustSpecificFuelConsumptiongkNsEditField.Value=TSFC;
    app.ErrorEditField.Value='All good!';
    
%Set table format    
    format short
%Values for total pressures and temperatures are entered into the table
% in the interface (pressure is converted into kPa)    
Table(1,:)={'Pt1', Pt1/1000, 'Tt1', Tt1};
Table(2,:)={'Pt2', Pt2/1000, 'Tt2', Tt2};
Table(3,:)={'Pt3', Pt3/1000, 'Tt3', Tt3};
Table(4,:)={'Pt4', Pt4/1000, 'Tt4', Tt4};
Table(5,:)={'Pt5', Pt5/1000, 'Tt5', Tt5};
Table(6,:)={'Pt6', Pt6/1000, 'Tt6', Tt6};
Table(7,:)={'Pt7', Pt7/1000, 'Tt7', Tt7};
Table(8,:)={'Pt8', Pt8/1000, 'Tt8', Tt8};
Table(9,:)={'Pt9', Pt9/1000, 'Tt9', Tt9};


app.UITable.Data=Table;

end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
%Plots of varying TET and HPCPR
            
%Specify the constants needed to complete the calculation (non-variables)
%Constants
gamma_cold=1.4;     %The cold heat capacity ratio
gamma_hot=1.333;    %The hot heat capacity ratio
Cp_cold=1005;       %Specific heat capacity of cold air
Cp_hot=1150;        %Specific heat capacity of hot air
R_cold=287;         %Cold gas constant
R_hot=287.3;        %Hot gas constant
LHV=43.1e+06;       %Fuel Lower Heating Value

%Set the parameters for the calculation as the values defined by the app
%user in the interface
Cruise_altitude=app.CruiseAltitudemEditField.Value ;        %Altitude of flight (m)
BPR=app.BypassRatioEditField.Value ;                        %Bypass Ratio (ratio of mass of cold air to the bypass duct vs mass of hot air to the engine)
N=app.NormalisedSpoolSpeedEditField.Value ;                 %Throttle setting, impacting the air intake mass flow rate, LPC ratio and HPC ratio 
M0=app.CruiseSpeedinMachEditField.Value;                    %Cruise Speed (in Mach)
TET=0;                                                      %Temperature of air at the exit of the combustor (K)
m_air_N_multiplier=app.IntakeNmultiplierkgsEditField.Value; %Value to be multiplied by 'N' to equal the intake air mass flow rate (kg/s)

eta_LPC=app.LPCEditField.Value ;        %LP Compressor isentropic efficiency
eta_HPC=app.HPCEditField.Value ;        %HP Compressor isentropic efficiency
eta_LPT=app.LPTEditField.Value ;        %LP Turbine isentropic efficiency
eta_HPT=app.HPTEditField.Value ;        %HP Turbine isentropic efficiency
eta_HPM=app.HPmEditField.Value ;        %HP Spool mechanical efficiency
eta_LPM=app.LPmEditField.Value ;        %LP Spool mechanical efficiency
eta_CC=app.CCEditField.Value ;          %Combustion efficiency

IPR=app.IntakePREditField.Value ;           %Intake Pressure Recovery
ITR=app.IntakeTREditField.Value ;           %Intake Temperature Recovery
PR_CC=app.CombustorPREditField.Value ;      %Combustor Pressure Ratio
JPPR=app.JetPipePREditField.Value ;         %Jet Pipe Pressure Recovery
JPTR=app.JetPipeTREditField.Value ;         %Jet Pipe Temperature Recovery
CNPR=app.CoreNozzlePREditField.Value ;      %Core Nozzle Pressure Recovery
CNTR=app.CoreNozzleTREditField.Value ;      %Core Nozzle Temperature Recovery
BNPR=app.BypassNozzlePREditField.Value ;    %Bypass Nozzle Pressure Recovery
BNTR=app.BypassNozzleTREditField.Value ;    %Bypass Nozzle Temperature Recovery

HPCPR=app.HPCPressureRatiomultiplierEditField.Value ;

%--------------------------------------------------------------------------
%INTAKE Stage 0--1
%The entry temperature, pressure and speed of sound are first calculated
%for the inputted cruise altitudes
T0 = 216.65;
a0 = 0;
P0 = 19416.7071;

%The velocity of the air flowing into the intake is then calculated
V0=M0*a0;

%Total Pressure and temperature are determined from isentropic
%relationships
Pt0=P0*(1+((gamma_cold-1)/2)*(M0^2))^(gamma_cold/(gamma_cold-1));
Tt0=T0*(Pt0/P0)^((gamma_cold-1)/(gamma_cold));

%Account for the pressure and temperature losses in the intake system
Pt1=Pt0*IPR;
Tt1=Tt0*ITR;
%--------------------------------------------------------------------------
%LP COMPRESSOR Stage 1--2
%Pressure ratio across the LPC is calculated
PR_crit_cold=((gamma_cold+1)/2)^(gamma_cold/(gamma_cold-1));
PR_LPC=PR_crit_cold*P0/(Pt1*BNPR);

%Pressure ratio across the LPC is used to find total pressure
Pt2=PR_LPC*Pt1;

%Isentropic relationships are used to find the ideal total temperature
Tt_2=Tt1*((Pt2/Pt1)^((gamma_cold-1)/gamma_cold));

%Actual temperature is found using the isentropic efficiency
Tt2=((Tt_2-Tt1)/eta_LPC)+Tt1;
%--------------------------------------------------------------------------
%HP COMPRESSOR Stage 2--3
%HP compressor pressure ratio can be calculated using given N value
PR_HPC=HPCPR*26.7*N^1.22;

%Pressure ratio across the HPC is used to find total pressure
Pt3=PR_HPC*Pt2;

%Isentropic relationships are used to find the ideal total temperature
Tt_3=Tt2*((Pt3/Pt2)^((gamma_cold-1)/gamma_cold));

%Actual temperature is found using the isentropic efficiency
Tt3=((Tt_3-Tt2)/eta_HPC)+Tt2;
%--------------------------------------------------------------------------
%COMBUSTOR Stage 3--4
%The total exit temperature of the combustor is given
while TET<2000
    TET=TET+1;
    
Tt4=TET;

%Exit pressure is calculated using the combustor pressure recovery
Pt4=PR_CC*Pt3;

%Energy balance is used to calculate the Air-to-Fuel ratio, AFR
AFR=((LHV*eta_CC)/(Cp_hot*(Tt4-Tt3)))-1;
%--------------------------------------------------------------------------
%HP TURBINE Stage 4--5
%Energy balance can be used to determine Tt5 and Pt5
Tt5=Tt4-((AFR*Cp_cold*(Tt3-Tt2))/((AFR+1)*Cp_hot*eta_HPM));

%Pt5 can be calculated using the isentropic temperature Tt_5
Tt_5=Tt4-((Tt4-Tt5)/eta_HPT);
Pt5=Pt4*(Tt_5/Tt4)^(gamma_hot/(gamma_hot-1));
%--------------------------------------------------------------------------
%LP TURBINE Stage 5--6
%Energy balance can be used to determine Tt6 and Pt6
Tt6=(Tt5-((BPR+1)*AFR*Cp_cold*(Tt2-Tt1))/((AFR+1)*Cp_hot*eta_LPM));

%Isentropic relationship is used to calculate Pt6
Tt_6=Tt5-(Tt5-Tt6)/eta_LPT;
Pt6=Pt5*(Tt_6/Tt5)^(gamma_hot/(gamma_hot-1));
%--------------------------------------------------------------------------
%JET PIPE Stage 6--7
%Calculate Pt7 and Tt7 using the given jet pipe properties
Pt7=Pt6*JPPR;
Tt7=Tt6*JPTR;
%--------------------------------------------------------------------------
%CORE (HOT JET) NOZZLE Stage 7--8
%Calculate Pt8 and Tt8 using the given core nozzle properties
Pt8=Pt7*CNPR;
Tt8=Tt7*CNTR;

%A convergent nozzle is used in this instance. Therefore, a choke test must
%be performed.
PR_crit_hot=((gamma_hot+1)/2)^(gamma_hot/(gamma_hot-1));
PR_ideal_hot=Pt8/P0;

%This program can only calculate choked flow, and hence an if clause is
%used to check
if PR_ideal_hot>=PR_crit_hot
    %If the nozzle is choked, the flow is fully expanded and hence M8=1.0
    M8=1.0;

%Knowing the flow is choked, static properties at the nozzle can be calculated 
TR_crit_hot=(gamma_hot+1)/2;
P8=Pt8/PR_crit_hot;
T8=Tt8/TR_crit_hot;
V8=M8*(gamma_hot*R_hot*T8)^0.5;

%If the flow is not choked, another method must be used to calculate the
%total properties at the exit of the nozzle
else 
    P8=P0;
    T8=Tt8/((Pt8/P0)^((gamma_hot-1)/gamma_hot));
    V8=(2*Cp_hot*(Tt8-T8))^0.5;
end

%Check to make sure that V8 doesn't return a negative number and warn the
%user is so
tf = isreal(V8);

if tf==0
   TSFC=0;
   net_uninstalled_thrust=0;
else
        
%Assuming the nozzle was designed for this condition, (or it is of variable
%design), the hot thrust can be calculated. First, the mass flows need to
%be defined.
m_air=N*m_air_N_multiplier;
m_core=(m_air)/(BPR+1);
m_bypass=m_air-m_core;
m_fuel=m_core/AFR;
m_8=m_core+m_fuel;

%The nozzle exit area can then be calculated
A8=(m_8*R_hot*T8)/(P8*V8);

%Thrust is calculated using the uninstalled thrust equation
Thrust_hot=(m_8*V8)+A8*(P8-P0)-(m_core*V0);
%--------------------------------------------------------------------------
%BYPASS NOZZLE Stage 2--9
%No heat addition through the bypass nozzle
Tt9=BNTR*Tt2;

%Pressure recovery is given
Pt9=BNPR*Pt2;

%Choked flow is a known condition from generating PR_LPC, and therefore,
M9=1.0;

TR_crit_cold=(gamma_cold+1)/2;
P9=Pt9/PR_crit_cold;
T9=Tt9/TR_crit_cold;

%Velocity of the flow can be calculated using the Mach number
V9=M9*(gamma_cold*R_cold*T9)^0.5;
m_9=m_bypass;

%Nozzle area is again calculated using the assumption listed above
A9=(m_9*R_cold*T9)/(P9*V9);

%The cold thrust can be calculated using the uninstalled thrust equation
Thrust_cold=(m_9*(V9-V0))+A9*(P9-P0);

%Total thrust is calculated by summing the hot and cold thrust values
net_uninstalled_thrust=Thrust_cold+Thrust_hot; %in N

%The Thrust specific fuel consumption can therefore be calculated, and
%converted into standard units of g/Ns
TSFC=m_fuel/net_uninstalled_thrust*1000000; %in g/Ns
Plot_1(TET,:)=[TET, TSFC, net_uninstalled_thrust];
end 
end

Plot_1(~Plot_1(:,1),:) = [];
x1=Plot_1(:,1);
y1=Plot_1(:,2);
z1=Plot_1(:,3);
x1=x1.';
y1=y1.';
z1=z1.';

plot(app.UIAxes,x1,y1);
plot(app.UIAxes2,x1,z1);

[~,I]=min(y1);
minimum_TSFC=x1(:,I);
app.TETMinimumTSFCEditField.Value=minimum_TSFC;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EnginePerformanceModelQ3Tab = uitab(app.TabGroup);
            app.EnginePerformanceModelQ3Tab.Title = 'Engine Performance Model (Q3)';
            app.RunButton = uibutton(app.EnginePerformanceModelQ3Tab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed2, true);
            app.RunButton.Text = 'Run';
            app.MECH3496THERMOFLUIDS3CW2Q3AEROSPACEPROPULSIONASSIGNMENTLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.MECH3496THERMOFLUIDS3CW2Q3AEROSPACEPROPULSIONASSIGNMENTLabel.Text = {'MECH3496 THERMOFLUIDS 3 CW2 Q3 - AEROSPACE PROPULSION ASSIGNMENT'; 'Engine Performance Matlab Model'; 'Edward Ball 201152378'};
            app.UITable = uitable(app.EnginePerformanceModelQ3Tab);
            app.UITable.ColumnName = {'Total Pressures'; 'kPa'; 'Total Temperatures'; 'K'};
            app.UITable.RowName = {''};
            app.UITable.ColumnSortable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @RunButtonPushed2, true);
            app.Image2 = uiimage(app.EnginePerformanceModelQ3Tab);
            app.Image2.ImageSource = 'University-of-Leeds-logo.png';
            app.Image3 = uiimage(app.EnginePerformanceModelQ3Tab);
            app.Image3.ImageSource = 'jacek-dylag-g5SVI7JU6j4-unsplash (1).jpg';
            app.BypassRatioEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.BypassRatioEditFieldLabel.Text = 'Bypass Ratio';
            app.BypassRatioEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.BypassRatioEditField.Value = 6.67;
            app.NormalisedSpoolSpeedEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.NormalisedSpoolSpeedEditFieldLabel.Text = 'Normalised Spool Speed';
            app.NormalisedSpoolSpeedEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.NormalisedSpoolSpeedEditField.Value = 1.13;
            app.CruiseSpeedinMachEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.CruiseSpeedinMachEditFieldLabel.Text = 'Cruise Speed (in Mach)';
            app.CruiseSpeedinMachEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.CruiseSpeedinMachEditField.Value = 0.8;
            app.TurbineEntryTemperatureKEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.TurbineEntryTemperatureKEditFieldLabel.Text = 'Turbine Entry Temperature (K)';
            app.TurbineEntryTemperatureKEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.TurbineEntryTemperatureKEditField.Value = 1200;
            app.IntakeNmultiplierkgsEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.IntakeNmultiplierkgsEditFieldLabel.Text = 'Intake N-multiplier (kg/s)';
            app.IntakeNmultiplierkgsEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.IntakeNmultiplierkgsEditField.Value = 408;
            app.LPCEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.LPCEditFieldLabel.Text = 'ηLPC';
            app.LPCEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.LPCEditField.Value = 0.93;
            app.HPCEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.HPCEditFieldLabel.Text = 'ηHPC';
            app.HPCEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.HPCEditField.Value = 0.91;
            app.LPTEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.LPTEditFieldLabel.Text = 'ηLPT';
            app.LPTEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.LPTEditField.Value = 0.95;
            app.HPTEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.HPTEditFieldLabel.Text = 'ηHPT';
            app.HPTEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.HPTEditField.Value = 0.94;
            app.HPmEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.HPmEditFieldLabel.Text = 'ηHPm';
            app.HPmEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.HPmEditField.Value = 0.99;
            app.LPmEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.LPmEditFieldLabel.Text = 'ηLPm';
            app.LPmEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.LPmEditField.Value = 0.99;
            app.CruiseAltitudemEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.CruiseAltitudemEditFieldLabel.Text = 'Cruise Altitude (m)';
            app.CruiseAltitudemEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.CruiseAltitudemEditField.Value = 9380;
            app.IntakePREditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.IntakePREditFieldLabel.Text = 'Intake PR';
            app.IntakePREditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.IntakePREditField.Value = 0.96;
            app.IntakeTREditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.IntakeTREditFieldLabel.Text = 'Intake TR';
            app.IntakeTREditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.IntakeTREditField.Value = 1;
            app.CombustorPREditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.CombustorPREditFieldLabel.Text = 'Combustor PR';
            app.CombustorPREditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.CombustorPREditField.Value = 0.98;
            app.HPCPRmultiplierEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.HPCPRmultiplierEditFieldLabel.Text = 'HPC PR multiplier';
            app.HPCPRmultiplierEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.HPCPRmultiplierEditField.Value = 1;
            app.JetPipePREditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.JetPipePREditFieldLabel.Text = 'Jet Pipe PR';
            app.JetPipePREditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.JetPipePREditField.Value = 1;
            app.JetPipeTREditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.JetPipeTREditFieldLabel.Text = 'Jet Pipe TR';
            app.JetPipeTREditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.JetPipeTREditField.Value = 1;
            app.BypassNozzlePREditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.BypassNozzlePREditFieldLabel.Text = 'Bypass Nozzle PR';
            app.BypassNozzlePREditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.BypassNozzlePREditField.Value = 0.9;
            app.BypassNozzleTREditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.BypassNozzleTREditFieldLabel.Text = 'Bypass Nozzle TR';
            app.BypassNozzleTREditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.BypassNozzleTREditField.Value = 1;
            app.CoreNozzlePREditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.CoreNozzlePREditFieldLabel.Text = 'Core Nozzle PR';
            app.CoreNozzlePREditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.CoreNozzlePREditField.Value = 1;
            app.CoreNozzleTREditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.CoreNozzleTREditFieldLabel.Text = 'Core Nozzle TR';
            app.CoreNozzleTREditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.CoreNozzleTREditField.Value = 1;
            app.CCEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.CCEditFieldLabel.Text = 'ηCC';
            app.CCEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.CCEditField.Value = 0.99;
            app.ThrustSpecificFuelConsumptiongkNsEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.ThrustSpecificFuelConsumptiongkNsEditFieldLabel.Text = 'Thrust Specific Fuel Consumption (g/kN.s)';
            app.ThrustSpecificFuelConsumptiongkNsEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.NetUninstalledThrustkNEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.NetUninstalledThrustkNEditFieldLabel.Text = 'Net Uninstalled Thrust (kN)';
            app.NetUninstalledThrustkNEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'numeric');
            app.InstructionsTextAreaLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.EnginePerformanceModelQ3Tab);
            app.InstructionsTextArea.Value = {'Leave all values as default for specified indivdual parameters. Values in blue are the unique engine parameters assigned to the student. Run tab 2 to generate TET used for Q5. See ''Help'' tab for guidance.'};
            app.ErrorEditFieldLabel = uilabel(app.EnginePerformanceModelQ3Tab);
            app.ErrorEditFieldLabel.Text = 'Error?';
            app.ErrorEditField = uieditfield(app.EnginePerformanceModelQ3Tab, 'text');
            app.AlteringTETandHPCPRQ4Tab = uitab(app.TabGroup);
            app.AlteringTETandHPCPRQ4Tab.Title = 'Altering TET and HPCPR (Q4)';
            app.UIAxes = uiaxes(app.AlteringTETandHPCPRQ4Tab);
            title(app.UIAxes, 'TET vs TSFC')
            xlabel(app.UIAxes, 'TET (K)')
            ylabel(app.UIAxes, 'TSFC')
            app.UIAxes2 = uiaxes(app.AlteringTETandHPCPRQ4Tab);
            title(app.UIAxes2, 'TET vs Thrust')
            xlabel(app.UIAxes2, 'TET')
            ylabel(app.UIAxes2, 'Thrust (N)')
            app.CalculateButton = uibutton(app.AlteringTETandHPCPRQ4Tab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TETMinimumTSFCEditFieldLabel = uilabel(app.AlteringTETandHPCPRQ4Tab);
            app.TETMinimumTSFCEditFieldLabel.Text = 'TET @ Minimum TSFC';
            app.TETMinimumTSFCEditField = uieditfield(app.AlteringTETandHPCPRQ4Tab, 'numeric');
            app.HPCPressureRatiomultiplierEditFieldLabel = uilabel(app.AlteringTETandHPCPRQ4Tab);
            app.HPCPressureRatiomultiplierEditFieldLabel.Text = 'HPC Pressure Ratio multiplier';
            app.HPCPressureRatiomultiplierEditField = uieditfield(app.AlteringTETandHPCPRQ4Tab, 'numeric');
            app.HPCPressureRatiomultiplierEditField.Value = 1;
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.TextArea = uitextarea(app.HelpTab);
            app.TextArea.Value = {'Engine_Performance_model is a computer model designed to calculate the performance of a two-spool, high bypass non-mixing engine, using gas turbine thermodynamic cycle analysis.'; ''; 'The model inputs and outputs can be seen on the ''Engine Performance Model'' tab of this app. '; ''; 'The model calculations are based on the assumption that the bypass nozzle is at the ''just choked'' condition, and hence the LP Compressor pressure ratio is not a variable, as this is calculated during the program running. '; ''; 'Please note due to this program having altitude as a variable, Matlab Aerospace Toolbox must be installed (this can be found at https://www.mathworks.com/products/aerospace-toolbox.html). '; ''; 'All efficiencies and recovery measures are to be entered as a numerical value from 0 to 1. Temperature Recovery values have been included for completeness and interest. For help on any of the variable names please consult the raw code. '; ''; 'Tab 2, entitled ''Altering TET and HPCPR (Q4)'' is used to calculate the TET at minimum TSFC for varying High Pressure Compressor Pressure Ratios. All values are pulled across from tab 1 (''Engine Performance Model (Q3)'') apart from the HPC pressure ratio multiplier, which is entered in tab 2. The value from tab 1 is ignored for this value. The program returns the TET value at which TSFC is minimum. '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Engine_Performance_model_201152378

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end