classdef engine_cycle_analyisis_tom_holiday_201015193 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup3                       matlab.ui.container.TabGroup
        UserInterfaceTab                matlab.ui.container.Tab
        RunButton                       matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        HPCPressureRatioButtonGroup     matlab.ui.container.ButtonGroup
        NormalResultsonSingleTabButton  matlab.ui.control.RadioButton
        IncreaseResultsonSingleTabButton  matlab.ui.control.RadioButton
        DecreaseResultsonSingleTabButton  matlab.ui.control.RadioButton
        All3ResultsonAllTabButton       matlab.ui.control.RadioButton
        TabGroup                        matlab.ui.container.TabGroup
        SingleTab                       matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        AllTab                          matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        TabGroup2                       matlab.ui.container.TabGroup
        SingleTab_2                     matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        AllTab_2                        matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        PositiveThrustValidResultsCheckBox  matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        BypassRatioBPREditFieldLabel    matlab.ui.control.Label
        BypassRatioBPREditField         matlab.ui.control.NumericEditField
        NormailisedSpoolSpeedNLabel     matlab.ui.control.Label
        NormailisedSpoolSpeedNEditField  matlab.ui.control.NumericEditField
        M0EditFieldLabel                matlab.ui.control.Label
        M0EditField                     matlab.ui.control.NumericEditField
        AltitudekmEditFieldLabel        matlab.ui.control.Label
        AltitudekmEditField             matlab.ui.control.NumericEditField
        P0fromaltitudeEditFieldLabel    matlab.ui.control.Label
        P0fromaltitudeEditField         matlab.ui.control.NumericEditField
        T0fromaltitudeEditFieldLabel    matlab.ui.control.Label
        T0fromaltitudeEditField         matlab.ui.control.NumericEditField
        ycoldEditFieldLabel             matlab.ui.control.Label
        ycoldEditField                  matlab.ui.control.NumericEditField
        yhotEditFieldLabel              matlab.ui.control.Label
        yhotEditField                   matlab.ui.control.NumericEditField
        CpcoldEditFieldLabel            matlab.ui.control.Label
        CpcoldEditField                 matlab.ui.control.NumericEditField
        CphotEditFieldLabel             matlab.ui.control.Label
        CphotEditField                  matlab.ui.control.NumericEditField
        RcoldEditFieldLabel             matlab.ui.control.Label
        RcoldEditField                  matlab.ui.control.NumericEditField
        RhotEditFieldLabel              matlab.ui.control.Label
        RhotEditField                   matlab.ui.control.NumericEditField
        IntakePressureRatioEditFieldLabel  matlab.ui.control.Label
        IntakePressureRatioEditField    matlab.ui.control.NumericEditField
        LPCPressureRatioEditFieldLabel  matlab.ui.control.Label
        LPCPressureRatioEditField       matlab.ui.control.NumericEditField
        CombustorPressureRatioLabel     matlab.ui.control.Label
        CombustorPressureRatioEditField  matlab.ui.control.NumericEditField
        BypassPressureRatioLabel        matlab.ui.control.Label
        BypassPressureRatioEditField    matlab.ui.control.NumericEditField
        LPCIsentropicEfficiencyLabel    matlab.ui.control.Label
        LPCIsentropicEfficiencyEditField  matlab.ui.control.NumericEditField
        HPCIsentropicEfficiencyLabel    matlab.ui.control.Label
        HPCIsentropicEfficiencyEditField  matlab.ui.control.NumericEditField
        HPTIsentropicEfficiencyLabel    matlab.ui.control.Label
        HPTIsentropicEfficiencyEditField  matlab.ui.control.NumericEditField
        LPTIsentropicEfficiencyLabel    matlab.ui.control.Label
        LPTIsentropicEfficiencyEditField  matlab.ui.control.NumericEditField
        CombustorMechanicalEfficiencyLabel  matlab.ui.control.Label
        CombustorMechanicalEfficiencyEditField  matlab.ui.control.NumericEditField
        HPSpoolMechanicalEfficiencyLabel  matlab.ui.control.Label
        HPSpoolMechanicalEfficiencyEditField  matlab.ui.control.NumericEditField
        LPSpoolMechanicalEfficiencyEditFieldLabel  matlab.ui.control.Label
        LPSpoolMechanicalEfficiencyEditField  matlab.ui.control.NumericEditField
        LowerHeatingValueEditFieldLabel  matlab.ui.control.Label
        LowerHeatingValueEditField      matlab.ui.control.NumericEditField
        TETLowerLimitLabel              matlab.ui.control.Label
        TETLowerLimitEditField          matlab.ui.control.NumericEditField
        TETUpperLimitLabel              matlab.ui.control.Label
        TETUpperLimitEditField          matlab.ui.control.NumericEditField
        TETKEditFieldLabel              matlab.ui.control.Label
        TETKEditField                   matlab.ui.control.NumericEditField
        M8EditFieldLabel                matlab.ui.control.Label
        M8EditField                     matlab.ui.control.NumericEditField
        CoreNozzleChokingConditionEditFieldLabel  matlab.ui.control.Label
        CoreNozzleChokingConditionEditField  matlab.ui.control.EditField
        M9EditFieldLabel                matlab.ui.control.Label
        M9EditField                     matlab.ui.control.NumericEditField
        BypassNozzleChokingConditionEditFieldLabel  matlab.ui.control.Label
        BypassNozzleChokingConditionEditField  matlab.ui.control.EditField
        V0EditFieldLabel                matlab.ui.control.Label
        V0EditField                     matlab.ui.control.NumericEditField
        V8EditFieldLabel                matlab.ui.control.Label
        V8EditField                     matlab.ui.control.NumericEditField
        V9EditFieldLabel                matlab.ui.control.Label
        V9EditField                     matlab.ui.control.NumericEditField
        BypassThrustNEditFieldLabel     matlab.ui.control.Label
        BypassThrustNEditField          matlab.ui.control.NumericEditField
        CoreThrustNEditFieldLabel       matlab.ui.control.Label
        CoreThrustNEditField            matlab.ui.control.NumericEditField
        TotalThrustNEditFieldLabel      matlab.ui.control.Label
        TotalThrustNEditField           matlab.ui.control.NumericEditField
        ThrustSpecificFuelConsumptionTSFCkgsNEditFieldLabel  matlab.ui.control.Label
        ThrustSpecificFuelConsumptionTSFCkgsNEditField  matlab.ui.control.NumericEditField
        ProgrammeInstructionsTextAreaLabel  matlab.ui.control.Label
        ProgrammeInstructionsTextArea   matlab.ui.control.TextArea
        MinimumTSFCValueEditFieldLabel  matlab.ui.control.Label
        MinimumTSFCValueEditField       matlab.ui.control.NumericEditField
        CorrespondingMinimumTETValueLabel  matlab.ui.control.Label
        CorrespondingMinimumTETValueEditField  matlab.ui.control.NumericEditField
        EnginePropertiesandConditionsLabel  matlab.ui.control.Label
        CompleteWorkingGasPropertiesLabel  matlab.ui.control.Label
        ErrorCheckLabel                 matlab.ui.control.Label
        ErrorCheckLabel_2               matlab.ui.control.Label
        MinimumTSFCValueLabel           matlab.ui.control.Label
        GeneralElectricCFM565CEngineModelTomHoliday201015193Label  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        UserGuideTab                    matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        Image2                          matlab.ui.control.Image
        TextArea_2                      matlab.ui.control.TextArea
        Image4                          matlab.ui.control.Image
    end

    %Input Parameters
    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
%MECH3496 - Thermofluids III - Aerospace Propulsion Assignment
%Tom Holiday
%201015193

%%%%This programme returns a full set of working gas properties for a General
%Electric CFM56-5C non-mixing turbofan engine by implementing a thermodynamic cycle.
%The input parameters can be changed to model any non-mixing turbofan
%engine. The model goes on to calculate thrust and TSFC and compare the results over a range of Turbine Entry Temperatures (TET's) This model makes assumptions which are listed below

%%%Analysis Model Assumptions
%The intake is assumed to have no temperature loss and to be adiabatic
%(Tt1 = Tt0) 
%%Jet Pipe assumed to be Adiabatic (Tt7 = Tt6)
%Core nozzle assumed to be Adiabatic (Tt8 = Tt7)
%Bypass duct assumed to be Adiabatic (Tt9 = Tt2)

%%%Stages
%(0-1) Intake Stage
%(1-2) LP compressor stage
%(2-3) HP compressor stage 
%(3-4) Combustor stage 
%(4-5) HP turbine stage 
%(5-6) LP turbine stage 
%(6-7) Jet pipe stage 
%(7-8) Core (hot jet) nozzle stage 
%(2-9) Bypass (cold jet) nozzle stage 

%%%Data Notation
%Pn = Static Pressure at Point n
%Ptn = Total Pressure at Point n
%Tn = Static Temperature at Point n
%Ttn = Total Temperature at Point n
%Ttn = Total Isentropic Temperature at Point n
%Vn = Air velocity at Point n

%Input Parameters

BPR = app.BypassRatioBPREditField.Value; % Bypass Ratio (BPR)
N = app.NormailisedSpoolSpeedNEditField.Value; % Normalised Spool Speed (N)
Altitude = app.AltitudekmEditField.Value; %Altitude (A)

P0 = app.P0fromaltitudeEditField.Value; %Ambient Pressure (from ISA)
T0 = app.T0fromaltitudeEditField.Value; %Ambient Temperature (from ISA)
ycold = app.ycoldEditField.Value; % Cold heat capacity ratio 
Rcold = app.RcoldEditField.Value; % Cold gas constant 
PRi = app.IntakePressureRatioEditField.Value; % Intake pressure recovery 
PRbypass = app.BypassPressureRatioEditField.Value; % Bypass nozzle pressure recovery

MFRair = 408*N; % Intake air mass flow rate 
IElpc = app.LPCIsentropicEfficiencyEditField.Value; % LP compressor isentropic efficiency  
IEhpc = app.HPCIsentropicEfficiencyEditField.Value; % HP compressor isentropic efficiency
PRcc = app.CombustorPressureRatioEditField.Value; % Combustor pressure ratio 
Ecc = app.CombustorMechanicalEfficiencyEditField.Value; % Combustor efficiency 
M0 = app.M0EditField.Value; % Intake Mach Number
LHV = app.LowerHeatingValueEditField.Value; % Fuel lower heating value 
IEhpt = app.HPTIsentropicEfficiencyEditField.Value; % HP turbine isentropic efficiency 
MEhpt = app.HPSpoolMechanicalEfficiencyEditField.Value; % HP spool mechanical efficiency 
IElpt = app.LPTIsentropicEfficiencyEditField.Value; % LP turbine isentropic efficiency 
MElpt = app.LPSpoolMechanicalEfficiencyEditField.Value; % LP spool mechanical efficiency 
PRjp = 1; % Jet pipe pressure recovery 
PRcn = 1; % Core nozzle pressure recovery 
yhot = app.yhotEditField.Value; % Hot heat capacity ratio 
Cpcold = app.CpcoldEditField.Value; % Cold specific heat at constant pressure 
Cphot = app.CphotEditField.Value; % Hot specific heat at constant pressure
Rhot = app.RhotEditField.Value; % Hot gas constant 


% (0-1) Intake Stage
 
V0 =  M0.*sqrt(ycold.*Rcold.*T0);

Pt0 = P0 * (1 + ((ycold - 1) / 2) * M0^2)^(ycold / (ycold -1 ));

Tt0 = T0 * (Pt0 / P0)^((ycold - 1)/ycold);

Pt1 = PRi*Pt0;

Tt1 = Tt0;

%Calculating PRlpc for when bypass nozzle is just choked

PRcritbypass = ((ycold + 1) / 2)^(ycold/(ycold-1));

PRlpc = (PRcritbypass * P0) / (PRbypass * Pt1); % LP compressor pressure ratio (as calculated in Q2a)

% Turbine entry temperature (TET) (n), ranging from n1 to n3 by an increment of n2.
 
n = 1;
n1 = app.TETLowerLimitEditField.Value;
n2 = 1;
n3 = app.TETUpperLimitEditField.Value;

for TET = n1:n2:n3

%%%%(1-2) LP Compressor Stage
 
Pt2 = PRlpc * Pt1;

Tt2i = Tt1 * (Pt2/Pt1)^((ycold-1)/ycold);

Tt2 = ((Tt2i-Tt1) / IElpc) + Tt1;
 
%%%%%(2-3) HP compressor stage 

%--------------------------------------------------------------------------------------

%The following varies the HPC Pressure Ratio: at normal, 10% increase and 10%
%decrease

if app.NormalResultsonSingleTabButton.Value == 1
    PRhpc = 26.7 * (N^1.22); % HP compressor pressure ratio
    all_3 = "No"; %This condition is used later to plot all 3 graphs when the 'All 3' button is selected
elseif app.IncreaseResultsonSingleTabButton.Value == 1
    PRhpc = 26.7 * (N^1.22) * 1.1; % HP compressor pressure ratio (10% Increase)
    all_3 = "No"; %This condition is used later to plot all 3 graphs when the 'All 3' button is selected
elseif app.DecreaseResultsonSingleTabButton.Value == 1
    PRhpc = 26.7 * (N^1.22) * 0.9; % HP compressor pressure ratio (10% Decrease)
    all_3 = "No"; %This condition is used later to plot all 3 graphs when the 'All 3' button is selected
else app.All3ResultsonAllTabButton.Value == 1
    PRhpc = 26.7 * (N^1.22);
    all_3 = "Yes"; %This condition is used later to plot all 3 graphs when the 'All 3' button is selected
end


Pt3 = PRhpc * Pt2;

Tt3i = Tt2 * (Pt3 / Pt2)^((ycold-1)/ycold);

Tt3 = ((Tt3i-Tt2) / IEhpc) + Tt2;
 
%(3-4) Combustor stage 
 
Tt4 = TET;

Pt4 = PRcc * Pt3;

AFR = ((LHV * Ecc)/(Cphot * (Tt4 - Tt3)))-1;
 
%(4-5) HP turbine stage 
 
Tt5 = Tt4 - (AFR * Cpcold * (Tt3 - Tt2))/((AFR + 1) * Cphot * MEhpt);

Tt5i = Tt4 - (Tt4 - Tt5) / IEhpt;

Pt5 = Pt4 * (Tt5i / Tt4)^(yhot/(yhot-1));
 
%(5-6) LP turbine stage 
 
MFRcore = MFRair / (1+BPR);

Tt6 = Tt5 - ((1 + BPR) * AFR * Cpcold * (Tt2 - Tt1))/((AFR + 1) * Cphot * MElpt);

Tt6i = Tt5 - (Tt5 - Tt6) / IElpt;

Pt6 = Pt5 * (Tt6i / Tt5)^(yhot/(yhot-1));
 
%(6-7) Jet pipe stage 

Pt7 = Pt6 * PRjp;

Tt7 = Tt6; %Jet Pipe assumed to be Adiabatic
 
%(7-8) Core (hot jet) nozzle stage 

%No total pressure or total temperature loss at core nozzle as there is no afterburner.
 
Pt8 = Pt7 * PRcn;

Tt8 = Tt7; %Core nozzle assumed to be Adiabatic

PRcritcore = ((yhot + 1) / 2)^(yhot/(yhot-1)); %Critical Pressure of Core

PRidealcore = Pt8 / P0; %Ideal Pressure Ratio of Core

TRcritcore = (yhot + 1) / 2; %Critical Temperature Ratio of Core

P8 = Pt8 / PRcritcore;

T8 = Tt8 / TRcritcore; 

if PRidealcore >= PRcritcore
    M8 = 1;
    %M8_Choked_Condition = "Choked";
else
    M8 = (sqrt(2 * Cphot * (Tt8 - T8))) / (sqrt(yhot * Rhot * T8));
    %M8_Choked_Condition = "Not Choked";
end

V8 = M8 * sqrt(yhot * Rhot * T8);

A8 = ((1 + (1 / AFR)) * ((Rhot * T8) / (P8 * V8))) * MFRcore;

Fncore = ((1 + (1 / AFR)) * V8 - V0 + ((A8 / MFRcore) * (P8 - P0))) * MFRcore;
 
%(2-9) Bypass (cold jet) nozzle stage 
 
Tt9 = Tt2; %Bypass duct assumed to be Adiabatic

Pt9 = PRbypass * Pt2;

PRcritbypass = ((ycold + 1) / 2)^(ycold/(ycold-1));

PRidealbypass = Pt9 / P0;

TRcritbypass = (ycold + 1) / 2;

P9 = Pt9 / PRcritbypass;

T9 = Tt9 / TRcritbypass;

if PRidealbypass >= PRcritbypass
    M9 = 1;
    %M9_Choked_Condition = "Choked";
elseif abs(PRidealbypass - PRcritbypass) < 0.001 %This is essentially 'PRidealbypass == PRcritbypass' but puts a tolerance of 0.001 in so they dont have to be EXACTLY equal 
    M9 = 1;
    %M9_Choked_Condition = "Just Choked";
else
    M9 = (sqrt(2 * Cpcold * (Tt9 - T9))) / (sqrt(ycold * Rcold * T9));
    %M9_Choked_Condition = "Not Choked";
end

V9 = M9 * sqrt(ycold * Rcold * T9);
 
A9 = (BPR * (Rhot * T8)/(P8 * V8)) * MFRcore;

Fnbypass = (BPR * (V9 - V0) + (A9 / MFRcore) * (P9 - P0)) * MFRcore;


%------------------------------------------------------------------------------


%%% 10% Increase in PRhpc data

PRhpc_SS1 = PRhpc * 1.1; %When PRhpc increases by 10%

%All parameters downstream of the HP Comressor now different (marked _SS1)

Pt3_SS1 = PRhpc_SS1 * Pt2; 

Tt3i_SS1 = Tt2 * (Pt3_SS1 / Pt2)^((ycold-1)/ycold);

Tt3_SS1 = ((Tt3i_SS1 - Tt2) / IEhpc) + Tt2;
 
%(3-4) Combustor stage 
 
Tt4 = TET;

Pt4_SS1 = PRcc * Pt3_SS1;

AFR_SS1 = ((LHV * Ecc)./(Cphot * (Tt4 - Tt3_SS1))) - 1;
 
%(4-5) HP turbine stage 
 
Tt5_SS1 = Tt4 - (AFR_SS1 * Cpcold * (Tt3_SS1 - Tt2))/((AFR_SS1 + 1) * Cphot * MEhpt);

Tt5i_SS1 = Tt4 - (Tt4 - Tt5_SS1) / IEhpt;

Pt5_SS1 = Pt4_SS1 * (Tt5i_SS1 / Tt4)^(yhot/(yhot-1));
 
%(5-6) LP turbine stage 
 
MFRcore = MFRair / (1 + BPR);

Tt6_SS1 = Tt5_SS1 - ((1 + BPR) * AFR_SS1 * Cpcold * (Tt2 - Tt1))/((AFR_SS1 + 1) * Cphot * MElpt);

Tt6i_SS1 = Tt5_SS1 - (Tt5_SS1 - Tt6_SS1) / IElpt;

Pt6_SS1 = Pt5_SS1 * (Tt6i_SS1 / Tt5_SS1)^(yhot/(yhot-1));
 
%(6-7) Jet pipe stage 

Pt7_SS1 = Pt6_SS1 * PRjp;

Tt7_SS1 = Tt6_SS1; %Jet Pipe assumed to be Adiabatic
 
%(7-8) Core (hot jet) nozzle stage 
 
Pt8_SS1 = Pt7_SS1 * PRcn;

Tt8_SS1 = Tt7_SS1; %Core Nozzle assumed to be Adiabatic

PRcritcore = ((yhot + 1) / 2)^(yhot/(yhot-1)); %Critical Pressure of Core

PRidealcore_SS1 = Pt8_SS1 / P0; %Ideal Pressure Ratio of Core

TRcritcore = (yhot + 1) / 2; %Critical Temperature Ratio of Core

P8_SS1 = Pt8_SS1 / PRcritcore;

T8_SS1 = Tt8_SS1 / TRcritcore;

if PRidealcore_SS1 >= PRcritcore
    M8_SS1 = 1;
    M8_SS1_Choked_Condition = "Choked";
else
    M8_SS1 = (sqrt(2 * Cphot * (Tt8_SS1 - T8_SS1))) / (sqrt(yhot * Rhot * T8_SS1));
    M8_SS1_Choked_Condition = "Not Choked";
end

V8_SS1 = M8_SS1 * sqrt(yhot * Rhot * T8_SS1);

A8_SS1 = ((1 + (1 ./ AFR_SS1)) .* ((Rhot .* T8_SS1) ./ (P8_SS1 .* V8_SS1))) .* MFRcore;

Fncore_SS1 = ((1 + (1 ./ AFR_SS1)) .* V8_SS1 - V0 + ((A8_SS1 ./ MFRcore) .* (P8_SS1 - P0))) .* MFRcore;
 
%(2-9) Bypass (cold jet) nozzle stage 
 
Tt9 = Tt2; %Bypass Duct assumed to be Adiabatic

Pt9 = PRbypass * Pt2;

PRcritbypass = ((ycold + 1) / 2)^(ycold/(ycold-1));

PRidealbypass = Pt9 / P0;

TRcritbypass = (ycold + 1) / 2;

P9 = Pt9 / PRcritbypass;

T9 = Tt9 / TRcritbypass;

if PRidealbypass >= PRcritbypass
    M9 = 1;
    M9_SS1_Choked_Condition = "Choked";
elseif abs(PRidealbypass - PRcritbypass) < 0.001 %This is essentially 'PRidealbypass == PRcritbypass' but puts a tolerance of 0.001 in so they dont have to be EXACTLY equal
    M9 = 1;
    M9_SS1_Choked_Condition = "Just Choked";
else
    M9 = (sqrt(2 * Cpcold * (Tt9 - T9))) / (sqrt(ycold * Rcold * T9));
    M9_SS1_Choked_Condition = "Not Choked";
end

V9 = M9 * sqrt(ycold * Rcold * T9);
 
A9_SS1 = (BPR * (Rhot * T8_SS1)/(P8_SS1 * V8_SS1)) * MFRcore;

Fnbypass_SS1 = (BPR * (V9 - V0) + (A9_SS1 / MFRcore) * (P9 - P0)) * MFRcore;


%-------------------------------------------------------------------------


%%% 10% Decrease in PRhpc data

PRhpc_SS2 = PRhpc * 0.9; %When PRhpc decreases by 10%

%All parameters downstream of the HP Comressor now different (marked _SS2)

Pt3_SS2 = PRhpc_SS2 * Pt2; 

Tt3i_SS2 = Tt2 * (Pt3_SS2 / Pt2)^((ycold-1)/ycold);

Tt3_SS2 = ((Tt3i_SS2 - Tt2) / IEhpc) + Tt2;
 
%(3-4) Combustor stage 
 
Tt4 = TET;

Pt4_SS2 = PRcc * Pt3_SS2;

AFR_SS2 = ((LHV * Ecc)./(Cphot * (Tt4 - Tt3_SS2))) - 1;
 
%(4-5) HP turbine stage 
 
Tt5_SS2 = Tt4 - (AFR_SS2 * Cpcold * (Tt3_SS2 - Tt2))/((AFR_SS2 + 1) * Cphot * MEhpt);

Tt5i_SS2 = Tt4 - (Tt4 - Tt5_SS2) / IEhpt;

Pt5_SS2 = Pt4_SS2 * (Tt5i_SS2 / Tt4)^(yhot/(yhot-1));
 
%(5-6) LP turbine stage 
 
MFRcore = MFRair / (1 + BPR);

Tt6_SS2 = Tt5_SS2 - ((1 + BPR) * AFR_SS2 * Cpcold * (Tt2 - Tt1))/((AFR_SS2 + 1) * Cphot * MElpt);

Tt6i_SS2 = Tt5_SS2 - (Tt5_SS2 - Tt6_SS2) / IElpt;

Pt6_SS2 = Pt5_SS2 * (Tt6i_SS2 / Tt5_SS2)^(yhot/(yhot-1));
 
%(6-7) Jet pipe stage 

Pt7_SS2 = Pt6_SS2 * PRjp;

Tt7_SS2 = Tt6_SS2; %Jet Pipe assumed to be Adiabatic
 
%(7-8) Core (hot jet) nozzle stage 
 
Pt8_SS2 = Pt7_SS2 * PRcn;

Tt8_SS2 = Tt7_SS2; %Core Nozzle assumed to be Adiabatic

PRcritcore = ((yhot + 1) / 2)^(yhot/(yhot-1)); %Critical Pressure of Core

PRidealcore_SS2 = Pt8_SS2 / P0; %Ideal Pressure Ratio of Core

TRcritcore = (yhot + 1) / 2; %Critical Temperature Ratio of Core

P8_SS2 = Pt8_SS2 / PRcritcore;

T8_SS2 = Tt8_SS2 / TRcritcore;

if PRidealcore_SS2 >= PRcritcore
    M8_SS2 = 1;
    M8_SS2_Choked_Condition = "Choked";
else
    M8_SS2 = (sqrt(2 * Cphot * (Tt8_SS2 - T8_SS2))) / (sqrt(yhot * Rhot * T8_SS2));
    M8_SS2_Choked_Condition = "Not Choked";
end

V8_SS2 = M8_SS2 * sqrt(yhot * Rhot * T8_SS2);

A8_SS2 = ((1 + (1 ./ AFR_SS2)) .* ((Rhot .* T8_SS2) ./ (P8_SS2 .* V8_SS2))) .* MFRcore;

Fncore_SS2 = ((1 + (1 ./ AFR_SS2)) .* V8_SS2 - V0 + ((A8_SS2 ./ MFRcore) .* (P8_SS2 - P0))) .* MFRcore;
 
%(2-9) Bypass (cold jet) nozzle stage 
 
Tt9 = Tt2; %Bypass Duct assumed to be Adiabatic

Pt9 = PRbypass * Pt2;

PRcritbypass = ((ycold + 1) / 2)^(ycold/(ycold-1));

PRidealbypass = Pt9 / P0;

TRcritbypass = (ycold + 1) / 2;

P9 = Pt9 / PRcritbypass;

T9 = Tt9 / TRcritbypass;

if PRidealbypass >= PRcritbypass
    M9 = 1;
    M9_SS2_Choked_Condition = "Choked";
elseif abs(PRidealbypass - PRcritbypass) < 0.001 %This is essentially 'PRidealbypass == PRcritbypass' but puts a tolerance of 0.001 in so they dont have to be EXACTLY equal
    M9 = 1;
    M9_SS2_Choked_Condition = "Just Choked";
else
    M9 = (sqrt(2 * Cpcold * (Tt9 - T9))) / (sqrt(ycold * Rcold * T9));
    M9_SS2_Choked_Condition = "Not Choked";
end

V9 = M9 * sqrt(ycold * Rcold * T9);
 
A9_SS2 = (BPR * (Rhot * T8_SS2)/(P8_SS2 * V8_SS2)) * MFRcore;

Fnbypass_SS2 = (BPR * (V9 - V0) + (A9_SS2 / MFRcore) * (P9 - P0)) * MFRcore;


%%%Thrust Calculations - Normal PRhpc%%%

Fntotal = Fncore + Fnbypass;
 
Fntotalvector(n) = Fntotal;
 
MFRbypass = MFRcore * BPR;

MFRfuel = MFRcore / AFR;
 
TSFC = MFRfuel / Fntotal; % Total specific fuel consumption
 
TSFCvector(n) = TSFC;

%The code below checks to make sure the thrust generated is positive for the complete range of TET values, if
%not (as a results of TET beign too low) it tells the user to increase n1 - the lower limit of TET.

Fn_Thrust_Condition = Fntotalvector(1);

if Fn_Thrust_Condition < 0
   app.CheckBox.Value = 1; 
   app.PositiveThrustValidResultsCheckBox.Value = 0; 
else
   app.PositiveThrustValidResultsCheckBox.Value = 1;
   app.CheckBox.Value = 0; 
end

%app.ThrustConditionEditField.ValueDisplayFormat = 'Thrust_Condition';

%%%Thrust Calculations - 10% Increase in PRhpc%%%

Fntotal_SS1 = Fncore_SS1 + Fnbypass_SS1;
 
Fntotalvector_SS1(n) = Fntotal_SS1;

MFRfuel_SS1 = MFRcore / AFR_SS1;
 
TSFC_SS1 = MFRfuel_SS1 / Fntotal_SS1; % Total specific fuel consumption
 
TSFCvector_SS1(n) = TSFC_SS1;
 
%%%Thrust Calculations - 10% Decrease in PRhpc%%%

Fntotal_SS2 = Fncore_SS2 + Fnbypass_SS2;
 
Fntotalvector_SS2(n) = Fntotal_SS2;

MFRfuel_SS2 = MFRcore / AFR_SS2;
 
TSFC_SS2 = MFRfuel_SS2 / Fntotal_SS2; % Total specific fuel consumption
 
TSFCvector_SS2(n) = TSFC_SS2;

n = n + 1;
 
end

%------------------------------------------------------------------------------


%The following plots the graphs depending on 

TET = n1:n2:n3;

if all_3 == "No"
    plot(app.UIAxes,TET,TSFCvector)
    plot(app.UIAxes2,TET,Fntotalvector)
else 
    plot(app.UIAxes4,TET,TSFCvector)
    plot(app.UIAxes4,TET,TSFCvector_SS1)
    plot(app.UIAxes4,TET,TSFCvector_SS2)
    legend(app.UIAxes4,'10% Decrease in HP Pressure Ratio','Standard HP Pressure Ratio','10% Increase in HP Pressure Ratio')
    hold(app.UIAxes4,'on')
    plot(app.UIAxes3,TET,Fntotalvector)
    plot(app.UIAxes3,TET,Fntotalvector_SS1)
    plot(app.UIAxes3,TET,Fntotalvector_SS2)
    legend(app.UIAxes3,'10% Decrease in HP Pressure Ratio','Standard HP Pressure Ratio','10% Increase in HP Pressure Ratio')
    hold(app.UIAxes3,'on')
    %app.UITable = 0;
    plot(app.UIAxes,0,0)
    plot(app.UIAxes2,0,0)
end

%Finding minimum value of TSFC and corresponding value of TET

[TSFCmin,ni] = min(TSFCvector);

TETmin = 1025 + ni;

app.MinimumTSFCValueEditField.Value = TSFCmin;

app.CorrespondingMinimumTETValueEditField.Value = TETmin;


%------------------------------------------------------------------------------


%To calculate values at a specific value of TET
%_sp denotes specific

TET_sp = app.TETKEditField.Value;

Tt4_sp = TET_sp;

Pt4_sp = PRcc * Pt3;

AFR_sp = ((LHV * Ecc)/(Cphot * (Tt4_sp - Tt3)))-1;
 
%(4-5) HP turbine stage 
 
Tt5_sp = Tt4_sp - (AFR_sp * Cpcold * (Tt3 - Tt2))/((AFR_sp + 1) * Cphot * MEhpt);

Tt5i_sp = Tt4_sp - (Tt4_sp - Tt5_sp) / IEhpt;

Pt5_sp = Pt4 * (Tt5i_sp / Tt4_sp)^(yhot/(yhot-1));
 
%(5-6) LP turbine stage 
 
MFRcore = MFRair / (1+BPR);

Tt6_sp = Tt5_sp - ((1 + BPR) * AFR_sp * Cpcold * (Tt2 - Tt1))/((AFR_sp + 1) * Cphot * MElpt);

Tt6i_sp = Tt5_sp - (Tt5_sp - Tt6_sp) / IElpt;

Pt6_sp = Pt5_sp * (Tt6i_sp / Tt5_sp)^(yhot/(yhot-1));
 
%(6-7) Jet pipe stage 

Pt7_sp = Pt6_sp * PRjp;

Tt7_sp = Tt6_sp; %Jet Pipe assumed to be Adiabatic
 
%(7-8) Core (hot jet) nozzle stage 
 
Pt8_sp = Pt7_sp * PRcn;

Tt8_sp = Tt7_sp; %Core nozzle assumed to be Adiabatic

PRcritcore_sp = ((yhot + 1) / 2)^(yhot/(yhot-1)); %Critical Pressure of Core

PRidealcore_sp = Pt8_sp / P0; %Ideal Pressure Ratio of Core

TRcritcore_sp = (yhot + 1) / 2; %Critical Temperature Ratio of Core

P8_sp = Pt8_sp / PRcritcore_sp;

T8_sp = Tt8_sp / TRcritcore_sp; 

if PRidealcore_sp >= PRcritcore_sp
    M8_sp = 1;
    app.CoreNozzleChokingConditionEditField.Value = "Yes";
else
    M8_sp = (sqrt(2 * Cphot * (Tt8_sp - T8_sp))) / (sqrt(yhot * Rhot * T8_sp));
    app.CoreNozzleChokingConditionEditField.Value = "No";
end

app.M8EditField.Value = M8_sp;

V8_sp = M8_sp * sqrt(yhot * Rhot * T8_sp);

A8_sp = ((1 + (1 / AFR_sp)) * ((Rhot * T8_sp) / (P8_sp * V8_sp))) * MFRcore;

Fncore_sp = ((1 + (1 / AFR_sp)) * V8_sp - V0 + ((A8_sp / MFRcore) * (P8_sp - P0))) * MFRcore;
 
%(2-9) Bypass (cold jet) nozzle stage 
 
Tt9_sp = Tt2; %Bypass duct assumed to be Adiabatic

Pt9_sp = PRbypass * Pt2;

PRcritbypass_sp = ((ycold + 1) / 2)^(ycold/(ycold-1));

PRidealbypass_sp = Pt9_sp / P0;

TRcritbypass_sp = (ycold + 1) / 2;

P9_sp = Pt9_sp / PRcritbypass_sp;

T9_sp = Tt9_sp / TRcritbypass_sp;

if PRidealbypass_sp >= PRcritbypass_sp
    M9_sp = 1;
    app.BypassNozzleChokingConditionEditField.Value = "Choked";
elseif abs(PRidealbypass_sp - PRcritbypass_sp) < 0.001 %This is essentially 'PRidealbypass_sp == PRcritbypass_sp' but puts a tolerance of 0.001 in so they dont have to be EXACTLY equal
    M9_sp = 1;
    app.BypassNozzleChokingConditionEditField.Value = "Just Choked";
else
    M9_sp = (sqrt(2 * Cpcold * (Tt9 - T9))) / (sqrt(ycold * Rcold * T9));
    app.BypassNozzleChokingConditionEditField.Value = "Not Choked";
end

app.M9EditField.Value = M9_sp;

V9_sp = M9_sp * sqrt(ycold * Rcold * T9_sp);
 
A9_sp = (BPR * (Rhot * T8_sp)/(P8_sp * V8_sp)) * MFRcore;

Fnbypass_sp = (BPR * (V9_sp - V0) + (A9_sp / MFRcore) * (P9_sp - P0)) * MFRcore;
 
%%%Thrust Calculations%%%

Fntotal_sp = Fncore_sp + Fnbypass_sp;
 
MFRbypass = MFRcore * BPR;

MFRfuel_sp = MFRcore / AFR_sp;
 
TSFC_sp = MFRfuel_sp / Fntotal_sp % Total specific fuel consumption


%Below Indexes the table on the Interface to display engine data correctly

if all_3 == "No"
table_column_1 = [Pt0,Pt1,Pt2,Pt3,Pt4_sp,Pt5_sp,Pt6_sp,Pt7_sp,Pt8_sp,Pt9_sp];
table_column_2 = [Tt0,Tt1,Tt2,Tt3,Tt4_sp,Tt5_sp,Tt6_sp,Tt7_sp,Tt8_sp,Tt9_sp];
table_column_3 = ["x",".",Tt2i,Tt3i,".",Tt5i_sp,Tt6i_sp,".",".","."];
table_column_4 = [P0,".",".",".",".",".",".",".",P8_sp,P9_sp];
table_column_5 = [T0,".",".",".",".",".",".",".",T8_sp,T9_sp];

app.UITable.Data(:,1) = table_column_1; 
app.UITable.Data(:,2) = table_column_2;
app.UITable.Data(:,3) = table_column_3;
app.UITable.Data(:,4) = table_column_4;
app.UITable.Data(:,5) = table_column_5;
else 
table_column_1 = ["N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A"];
table_column_2 = ["N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A"];
table_column_3 = ["N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A"];
table_column_4 = ["N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A"];
table_column_5 = ["N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A","N/A"];

app.UITable.Data(:,1) = table_column_1; 
app.UITable.Data(:,2) = table_column_2;
app.UITable.Data(:,3) = table_column_3;
app.UITable.Data(:,4) = table_column_4;
app.UITable.Data(:,5) = table_column_5;
end

app.V0EditField.Value = V0;
app.V8EditField.Value = V8_sp;
app.V9EditField.Value = V9_sp;
app.BypassThrustNEditField.Value = Fnbypass_sp;
app.CoreThrustNEditField.Value = Fncore_sp;
app.TotalThrustNEditField.Value = Fntotal_sp;
app.ThrustSpecificFuelConsumptionTSFCkgsNEditField.Value = TSFC_sp;


%-----------------------------------------------------------------------------------------------


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.UserInterfaceTab = uitab(app.TabGroup3);
            app.UserInterfaceTab.Title = 'User Interface';
            app.RunButton = uibutton(app.UserInterfaceTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UITable = uitable(app.UserInterfaceTab);
            app.UITable.ColumnName = {'Total Pressure (Pa)'; 'Total Temperature (K)'; 'Total Isentropic Temperature (K)'; 'Static Pressure (Pa)'; 'Static Temperature (K)'};
            app.UITable.RowName = {'Point 0'; 'Point 1'; 'Point 2'; 'Point 3'; 'Point 4'; 'Point 5'; 'Point 6'; 'Point 7'; 'Point 8'; 'Point 9'; ''; ''};
            app.HPCPressureRatioButtonGroup = uibuttongroup(app.UserInterfaceTab);
            app.HPCPressureRatioButtonGroup.Title = 'HPC Pressure Ratio';
            app.NormalResultsonSingleTabButton = uiradiobutton(app.HPCPressureRatioButtonGroup);
            app.NormalResultsonSingleTabButton.Text = {'Normal'; '(Results on "Single" Tab)'};
            app.NormalResultsonSingleTabButton.Value = true;
            app.IncreaseResultsonSingleTabButton = uiradiobutton(app.HPCPressureRatioButtonGroup);
            app.IncreaseResultsonSingleTabButton.Text = {'10% Increase'; '(Results on "Single" Tab)'};
            app.DecreaseResultsonSingleTabButton = uiradiobutton(app.HPCPressureRatioButtonGroup);
            app.DecreaseResultsonSingleTabButton.Text = {'10% Decrease'; '(Results on "Single" Tab)'};
            app.All3ResultsonAllTabButton = uiradiobutton(app.HPCPressureRatioButtonGroup);
            app.All3ResultsonAllTabButton.Text = 'All 3 (Results on "All" Tab)';
            app.TabGroup = uitabgroup(app.UserInterfaceTab);
            app.SingleTab = uitab(app.TabGroup);
            app.SingleTab.Title = 'Single';
            app.UIAxes2 = uiaxes(app.SingleTab);
            title(app.UIAxes2, 'Thrust vs TET')
            xlabel(app.UIAxes2, 'TET, K')
            ylabel(app.UIAxes2, 'Thrust, N')
            app.AllTab = uitab(app.TabGroup);
            app.AllTab.Title = 'All';
            app.UIAxes3 = uiaxes(app.AllTab);
            title(app.UIAxes3, 'Thrust vs TET')
            xlabel(app.UIAxes3, 'TET, K')
            ylabel(app.UIAxes3, 'Thrust, N')
            app.TabGroup2 = uitabgroup(app.UserInterfaceTab);
            app.SingleTab_2 = uitab(app.TabGroup2);
            app.SingleTab_2.Title = 'Single';
            app.UIAxes = uiaxes(app.SingleTab_2);
            title(app.UIAxes, 'TSFC vs TET')
            xlabel(app.UIAxes, 'TET, K')
            ylabel(app.UIAxes, 'TSFC, kg.s/N')
            app.AllTab_2 = uitab(app.TabGroup2);
            app.AllTab_2.Title = 'All';
            app.UIAxes4 = uiaxes(app.AllTab_2);
            title(app.UIAxes4, 'TSFC vs TET')
            xlabel(app.UIAxes4, 'TET, K')
            ylabel(app.UIAxes4, 'TSFC, kg.s/N')
            app.PositiveThrustValidResultsCheckBox = uicheckbox(app.UserInterfaceTab);
            app.PositiveThrustValidResultsCheckBox.Text = 'Positive Thrust - Valid Results';
            app.CheckBox = uicheckbox(app.UserInterfaceTab);
            app.CheckBox.Text = {'Negative Thrust - Invalid Results'; '(Re-run with higher TET Lower Limit)'};
            app.BypassRatioBPREditFieldLabel = uilabel(app.UserInterfaceTab);
            app.BypassRatioBPREditFieldLabel.Text = 'Bypass Ratio (BPR)';
            app.BypassRatioBPREditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.BypassRatioBPREditField.Value = 7.41;
            app.NormailisedSpoolSpeedNLabel = uilabel(app.UserInterfaceTab);
            app.NormailisedSpoolSpeedNLabel.Text = {'Normailised Spool'; 'Speed (N)'};
            app.NormailisedSpoolSpeedNEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.NormailisedSpoolSpeedNEditField.Value = 1.19;
            app.M0EditFieldLabel = uilabel(app.UserInterfaceTab);
            app.M0EditFieldLabel.Text = {'M0'; ''};
            app.M0EditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.M0EditField.Value = 0.8;
            app.AltitudekmEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.AltitudekmEditFieldLabel.Text = 'Altitude (km)';
            app.AltitudekmEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.AltitudekmEditField.Value = 11.98;
            app.P0fromaltitudeEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.P0fromaltitudeEditFieldLabel.Text = 'P0 (from altitude)';
            app.P0fromaltitudeEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.P0fromaltitudeEditField.Value = 19416;
            app.T0fromaltitudeEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.T0fromaltitudeEditFieldLabel.Text = 'T0 (from altitude)';
            app.T0fromaltitudeEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.T0fromaltitudeEditField.Value = 216.65;
            app.ycoldEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.ycoldEditFieldLabel.Text = {'ycold'; ''};
            app.ycoldEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.ycoldEditField.Value = 1.4;
            app.yhotEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.yhotEditFieldLabel.Text = {'yhot'; ''};
            app.yhotEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.yhotEditField.Value = 1.333;
            app.CpcoldEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.CpcoldEditFieldLabel.Text = 'Cp,cold';
            app.CpcoldEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.CpcoldEditField.Value = 1005;
            app.CphotEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.CphotEditFieldLabel.Text = 'Cp,hot';
            app.CphotEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.CphotEditField.Value = 1150;
            app.RcoldEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.RcoldEditFieldLabel.Text = 'Rcold';
            app.RcoldEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.RcoldEditField.Value = 287;
            app.RhotEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.RhotEditFieldLabel.Text = {'Rhot'; ''};
            app.RhotEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.RhotEditField.Value = 287.3;
            app.IntakePressureRatioEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.IntakePressureRatioEditFieldLabel.Text = {'Intake Pressure Ratio'; ''};
            app.IntakePressureRatioEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.IntakePressureRatioEditField.Value = 0.96;
            app.LPCPressureRatioEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.LPCPressureRatioEditFieldLabel.Text = {'LPC Pressure Ratio'; ''};
            app.LPCPressureRatioEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.LPCPressureRatioEditField.Value = 1.437;
            app.CombustorPressureRatioLabel = uilabel(app.UserInterfaceTab);
            app.CombustorPressureRatioLabel.Text = {'Combustor'; 'Pressure Ratio'; ''};
            app.CombustorPressureRatioEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.CombustorPressureRatioEditField.Value = 0.98;
            app.BypassPressureRatioLabel = uilabel(app.UserInterfaceTab);
            app.BypassPressureRatioLabel.Text = {'Bypass'; 'Pressure Ratio'; ''};
            app.BypassPressureRatioEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.BypassPressureRatioEditField.Value = 0.9;
            app.LPCIsentropicEfficiencyLabel = uilabel(app.UserInterfaceTab);
            app.LPCIsentropicEfficiencyLabel.Text = {'LPC Isentropic'; 'Efficiency'; ''};
            app.LPCIsentropicEfficiencyEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.LPCIsentropicEfficiencyEditField.Value = 0.93;
            app.HPCIsentropicEfficiencyLabel = uilabel(app.UserInterfaceTab);
            app.HPCIsentropicEfficiencyLabel.Text = {'HPC Isentropic'; 'Efficiency'; ''};
            app.HPCIsentropicEfficiencyEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.HPCIsentropicEfficiencyEditField.Value = 0.91;
            app.HPTIsentropicEfficiencyLabel = uilabel(app.UserInterfaceTab);
            app.HPTIsentropicEfficiencyLabel.Text = {'HPT Isentropic'; 'Efficiency'; ''};
            app.HPTIsentropicEfficiencyEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.HPTIsentropicEfficiencyEditField.Value = 0.94;
            app.LPTIsentropicEfficiencyLabel = uilabel(app.UserInterfaceTab);
            app.LPTIsentropicEfficiencyLabel.Text = {'LPT Isentropic'; 'Efficiency'; ''};
            app.LPTIsentropicEfficiencyEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.LPTIsentropicEfficiencyEditField.Value = 0.95;
            app.CombustorMechanicalEfficiencyLabel = uilabel(app.UserInterfaceTab);
            app.CombustorMechanicalEfficiencyLabel.Text = {'Combustor'; 'Mechanical Efficiency'; ''};
            app.CombustorMechanicalEfficiencyEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.CombustorMechanicalEfficiencyEditField.Value = 0.99;
            app.HPSpoolMechanicalEfficiencyLabel = uilabel(app.UserInterfaceTab);
            app.HPSpoolMechanicalEfficiencyLabel.Text = {'HP Spool'; 'Mechanical Efficiency'; ''};
            app.HPSpoolMechanicalEfficiencyEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.HPSpoolMechanicalEfficiencyEditField.Value = 0.99;
            app.LPSpoolMechanicalEfficiencyEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.LPSpoolMechanicalEfficiencyEditFieldLabel.Text = {'LP Spool'; 'Mechanical Efficiency'; ''};
            app.LPSpoolMechanicalEfficiencyEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.LPSpoolMechanicalEfficiencyEditField.Value = 0.99;
            app.LowerHeatingValueEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.LowerHeatingValueEditFieldLabel.Text = {'Lower Heating Value'; ''};
            app.LowerHeatingValueEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.LowerHeatingValueEditField.Value = 43100000;
            app.TETLowerLimitLabel = uilabel(app.UserInterfaceTab);
            app.TETLowerLimitLabel.Text = 'TET Lower Limit';
            app.TETLowerLimitEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.TETLowerLimitEditField.Value = 1025;
            app.TETUpperLimitLabel = uilabel(app.UserInterfaceTab);
            app.TETUpperLimitLabel.Text = 'TET Upper Limit';
            app.TETUpperLimitEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.TETUpperLimitEditField.Value = 2500;
            app.TETKEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.TETKEditFieldLabel.Text = {'TET (K)'; ''};
            app.TETKEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.TETKEditField.Value = 1200;
            app.M8EditFieldLabel = uilabel(app.UserInterfaceTab);
            app.M8EditFieldLabel.Text = {'M8'; ''};
            app.M8EditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.CoreNozzleChokingConditionEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.CoreNozzleChokingConditionEditFieldLabel.Text = {'Core Nozzle'; 'Choking Condition'};
            app.CoreNozzleChokingConditionEditField = uieditfield(app.UserInterfaceTab, 'text');
            app.M9EditFieldLabel = uilabel(app.UserInterfaceTab);
            app.M9EditFieldLabel.Text = {'M9'; ''};
            app.M9EditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.BypassNozzleChokingConditionEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.BypassNozzleChokingConditionEditFieldLabel.Text = {'Bypass Nozzle'; 'Choking Condition'};
            app.BypassNozzleChokingConditionEditField = uieditfield(app.UserInterfaceTab, 'text');
            app.V0EditFieldLabel = uilabel(app.UserInterfaceTab);
            app.V0EditFieldLabel.Text = {'V0'; ''};
            app.V0EditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.V8EditFieldLabel = uilabel(app.UserInterfaceTab);
            app.V8EditFieldLabel.Text = {'V8'; ''};
            app.V8EditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.V9EditFieldLabel = uilabel(app.UserInterfaceTab);
            app.V9EditFieldLabel.Text = {'V9'; ''};
            app.V9EditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.BypassThrustNEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.BypassThrustNEditFieldLabel.Text = {'Bypass Thrust, N'; ''};
            app.BypassThrustNEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.CoreThrustNEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.CoreThrustNEditFieldLabel.Text = {'Core Thrust, N'; ''};
            app.CoreThrustNEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.TotalThrustNEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.TotalThrustNEditFieldLabel.Text = {'Total Thrust, N'; ''};
            app.TotalThrustNEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.ThrustSpecificFuelConsumptionTSFCkgsNEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.ThrustSpecificFuelConsumptionTSFCkgsNEditFieldLabel.Text = {'Thrust Specific Fuel'; 'Consumption (TSFC)'; 'kg.s/N'; ''};
            app.ThrustSpecificFuelConsumptionTSFCkgsNEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.ProgrammeInstructionsTextAreaLabel = uilabel(app.UserInterfaceTab);
            app.ProgrammeInstructionsTextAreaLabel.Text = 'Programme Instructions';
            app.ProgrammeInstructionsTextArea = uitextarea(app.UserInterfaceTab);
            app.ProgrammeInstructionsTextArea.Value = {'This programme acheives two primary objectives:'; ''; 'a) It outputs working gas properties for the engine. The conditions and properties for the engine can be varied by the user.'; ''; 'b) Returns Thrust and Thrust Specific Fuel Consumption (TSFC) outputs for a range of Turbin Entry Temperature (TET) values. '; ''; 'A user manual for this programme is available on the following tab.'};
            app.MinimumTSFCValueEditFieldLabel = uilabel(app.UserInterfaceTab);
            app.MinimumTSFCValueEditFieldLabel.Text = 'Minimum TSFC Value';
            app.MinimumTSFCValueEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.CorrespondingMinimumTETValueLabel = uilabel(app.UserInterfaceTab);
            app.CorrespondingMinimumTETValueLabel.Text = {'Corresponding Minimum '; 'TET Value'};
            app.CorrespondingMinimumTETValueEditField = uieditfield(app.UserInterfaceTab, 'numeric');
            app.EnginePropertiesandConditionsLabel = uilabel(app.UserInterfaceTab);
            app.EnginePropertiesandConditionsLabel.Text = 'Engine Properties and Conditions';
            app.CompleteWorkingGasPropertiesLabel = uilabel(app.UserInterfaceTab);
            app.CompleteWorkingGasPropertiesLabel.Text = 'Complete Working Gas Properties';
            app.ErrorCheckLabel = uilabel(app.UserInterfaceTab);
            app.ErrorCheckLabel.Text = 'Error Check';
            app.ErrorCheckLabel_2 = uilabel(app.UserInterfaceTab);
            app.ErrorCheckLabel_2.Text = 'Thrust & Thrust Specific Fuel Consumption vs Turbine Entry Temeprature';
            app.MinimumTSFCValueLabel = uilabel(app.UserInterfaceTab);
            app.MinimumTSFCValueLabel.Text = 'Minimum TSFC Value';
            app.GeneralElectricCFM565CEngineModelTomHoliday201015193Label = uilabel(app.UserInterfaceTab);
            app.GeneralElectricCFM565CEngineModelTomHoliday201015193Label.Text = {'General Electric CFM56-5C Engine Cycle Analysis'; 'Tom Holiday - 201015193'};
            app.Image3 = uiimage(app.UserInterfaceTab);
            app.Image3.ImageSource = 'Picture 3.png';
            app.UserGuideTab = uitab(app.TabGroup3);
            app.UserGuideTab.Title = 'User Guide';
            app.TextArea = uitextarea(app.UserGuideTab);
            app.TextArea.Value = {'MECH3496 - Thermofluids III - Aerospace Propulsion Assignment - Tom Holiday - 201015193'; ''; 'This programme returns a full set of working gas properties for a General Electric CFM56-5C non-mixing turbofan engine by implementing a thermodynamic cycle analysis.The input parameters can be changed to model any non-mixing turbofan engine. The model goes on to calculate thrust and TSFC and compare the results over a rnage of Turbine Entry Temperatures (TET''s) This model makes assumptions which are listed below'; ''; 'Analysis Model Assumptions'; 'The intake is assumed to have no temperature loss and to be adiabatic (Tt1 = Tt0) '; 'Jet Pipe assumed to be Adiabatic (Tt7 = Tt6)'; 'Core nozzle assumed to be Adiabatic (Tt8 = Tt7)'; 'Bypass duct assumed to be Adiabatic (Tt9 = Tt2)'; 'LP and HP spools are assumed to have 100% transmission efficiency'; ''; 'Stages'; '(0-1) Intake Stage'; '(1-2) LP compressor stage'; '(2-3) HP compressor stage '; '(3-4) Combustor stage '; '(4-5) HP turbine stage '; '(5-6) LP turbine stage '; '(6-7) Jet pipe stage '; '(7-8) Core (hot jet) nozzle stage '; '(2-9) Bypass (cold jet) nozzle stage '; ''; 'Data Notation'; 'Mo =Velocity Mach Number '; 'Vn = Air velocity at Point n'};
            app.Image2 = uiimage(app.UserGuideTab);
            app.Image2.ImageSource = 'university-leeds_700x301.png';
            app.TextArea_2 = uitextarea(app.UserGuideTab);
            app.TextArea_2.Value = {'The top left of the User Interface allows the user to input specific engine properties and engines. The default settings are those provided as part of the MECH3496 assessment and represent the CFM56-5C engine. A particular input for concern is Turbine Entry Temperature (TET) as it has considerable effect on engine performance and is a key design consideration. Hence this is emboldened.'; ''; 'For the inputted parameters, when run the programme returns a full set of working gas properties in the bottom left of the UI as well as other outputs such as thrust and thrust specific fuel consumption (TSFC). It also outputs the choking condition at the bypass and core nozzle. '; ''; 'The programme also has the capability to return graphs shwoing thrust and TSFC against a range of TET values. The user can manually vary the range of TET values. When the lower limit of TET is below a certain value Thrust becomes negative and the results become invalid and this is displayed as "Negative Thrust - Invalid Results (Re-run with higher TET Lower Limit)" in the Errock Check area of the UI, bottom right. The programme also allows the user to very the pressure ratio at the high pressure compressor (HPC); another significant design consideration and plots the corresponidng graphs and returns the full working gas properties. '; ''; 'The final feature this programme has is the minmum TSFC value section, bottom right. This return the minimum possible TSFC value for the range of TET values inputted and also return the TET value at which this occurs. This makes the programme extermely useful to engine designers as they can input any engine charachtersitcs into the programme and retrun the TET value that returns the minimum TSFC. This has large implications in making engine designs more efficient '};
            app.Image4 = uiimage(app.UserGuideTab);
            app.Image4.ImageSource = 'Picture 1.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = engine_cycle_analyisis_tom_holiday_201015193

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end